classdef gcs2020 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RyersonUniversityPanel  matlab.ui.container.Panel
        Image                   matlab.ui.control.Image
        TeamTomahawkLabel       matlab.ui.control.Label
        TeamID6400Label         matlab.ui.control.Label
        DateTimeDisplay         matlab.ui.control.Label
        MenuPanel               matlab.ui.container.Panel
        ConnectButton           matlab.ui.control.Button
        StopBurnButton          matlab.ui.control.Button
        COMSelectDropDownLabel  matlab.ui.control.Label
        COMSelectDropDown       matlab.ui.control.DropDown
        StartBurnButton         matlab.ui.control.Button
        ReconnectButton         matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
        IsDeployedLampLabel     matlab.ui.control.Label
        IsDeployedLamp          matlab.ui.control.Lamp
        XaxisDropDownLabel      matlab.ui.control.Label
        graphXAxis              matlab.ui.control.DropDown
        YaxisDropDownLabel      matlab.ui.control.Label
        graphYAxis              matlab.ui.control.DropDown
        EndTelemetryButton      matlab.ui.control.Button
        AltitudeLabel           matlab.ui.control.Label
        altText                 matlab.ui.control.Label
        currentStateButton      matlab.ui.control.Button
    end

    properties (Access = public)
        probeData;
        xProbe;
        yProbe;
    end
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global obj;
            setCOMPort(app);
        end

        % Size changed function: MenuPanel
        function menu(app, event)
            position = app.MenuPanel.Position;
            
        end

        % Button pushed function: ConnectButton
        function arduinoConnectButton(app, event)
            global ard;
            selectedPort = app.COMSelectDropDown.Value
            obj = serial(selectedPort);
            app.UITable.Data = [];
            app.DateTimeDisplay.Text = datestr(datetime);
            ard = connectFunction(selectedPort, app, datestr(datetime));
        end

        % Button pushed function: StopBurnButton
        function stopBurn(app, event)
            
        end

        % Button pushed function: ReconnectButton
        function rcnBtn(app, event)
            
        end

        % Callback function
        function graphxAxis(app, event)
            value = app.graphXAxis.Value;
            
        end

        % Value changed function: graphYAxis
        function graphyAxis(app, event)
            value = app.graphYAxis.Value;
            
            
        end

        % Cell edit callback: UITable
        function tableEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITable
        function cellSelectEdit(app, event)
            indices = event.Indices;
            
        end

        % Display data changed function: UITable
        function dispDataChange(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Value changed function: COMSelectDropDown
        function COMSelect(app, event)
            value = app.COMSelectDropDown.Value;
            setCOMPort(value);

        end

        % Button pushed function: EndTelemetryButton
        function stopTimer(app, event)
            stopTimerFunction();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RyersonUniversityPanel = uipanel(app.UIFigure);
            app.RyersonUniversityPanel.Title = 'Ryerson University';
            app.Image = uiimage(app.RyersonUniversityPanel);
            app.Image.ImageSource = 'Team_Tomahawk_logo.PNG';
            app.TeamTomahawkLabel = uilabel(app.RyersonUniversityPanel);
            app.TeamTomahawkLabel.Text = 'Team Tomahawk';
            app.TeamID6400Label = uilabel(app.RyersonUniversityPanel);
            app.TeamID6400Label.Text = 'Team ID: 6400';
            app.DateTimeDisplay = uilabel(app.RyersonUniversityPanel);
            app.DateTimeDisplay.Text = 'Date and Time';
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuPanel.Title = 'Menu';
            app.MenuPanel.SizeChangedFcn = createCallbackFcn(app, @menu, true);
            app.ConnectButton = uibutton(app.MenuPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @arduinoConnectButton, true);
            app.ConnectButton.Text = 'Connect';
            app.StopBurnButton = uibutton(app.MenuPanel, 'push');
            app.StopBurnButton.ButtonPushedFcn = createCallbackFcn(app, @stopBurn, true);
            app.StopBurnButton.Text = 'Stop Burn';
            app.COMSelectDropDownLabel = uilabel(app.MenuPanel);
            app.COMSelectDropDownLabel.Text = 'COM Select';
            app.COMSelectDropDown = uidropdown(app.MenuPanel);
            app.COMSelectDropDown.Items = {'NA', ''};
            app.COMSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @COMSelect, true);
            app.COMSelectDropDown.Value = 'NA';
            app.StartBurnButton = uibutton(app.MenuPanel, 'push');
            app.StartBurnButton.Text = 'Start Burn';
            app.ReconnectButton = uibutton(app.UIFigure, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @rcnBtn, true);
            app.ReconnectButton.Text = 'Re-connect';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Team ID (#)'; 'Mission Time (Sec)'; 'Packet Count (#)'; 'Altitude (m)'; 'Pressure (pa)'; 'Temperature (Degrees C)'; 'Voltage (v)'; 'GPS Time (sec)'; 'GPS Latitude (deg)'; 'GPS Longitude (deg)'; 'GPS Altitude (m)'; 'GPS Sats (#)'; 'Air Speed (m/s)'; 'Software State (#)'; 'Particle Count (mg/m^3)'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @tableEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @cellSelectEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @dispDataChange, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Telemetry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.IsDeployedLampLabel = uilabel(app.UIFigure);
            app.IsDeployedLampLabel.Text = 'Is Deployed:';
            app.IsDeployedLamp = uilamp(app.UIFigure);
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.graphXAxis = uidropdown(app.UIFigure);
            app.graphXAxis.Items = {'Time (sec)', 'Packet Count (#)', 'Altitude (m)', 'Pressure (pa)', 'Temperature (degrees C)', 'Voltage (volts)', 'GPS Time (sec)', 'GPS Latitude (deg)', 'GPS Longitude (deg)', 'GPS Altitude (m)', 'GPS SATS (#)', 'Air Speed (m/s)', 'Software State (#)', 'Particle Count (mg/m^3)'};
            app.graphXAxis.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.graphXAxis.Value = '1';
            app.YaxisDropDownLabel = uilabel(app.UIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.graphYAxis = uidropdown(app.UIFigure);
            app.graphYAxis.Items = {'Time (sec)', 'Packet Count (#)', 'Altitude (m)', 'Pressure (pa)', 'Temperature (degrees C)', 'Voltage (volts)', 'GPS Time (sec)', 'GPS Latitude (deg)', 'GPS Longitude (deg)', 'GPS Altitude (m)', 'GPS SATS (#)', 'Air Speed (m/s)', 'Software State (#)', 'Particle Count (mg/m^3)'};
            app.graphYAxis.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.graphYAxis.ValueChangedFcn = createCallbackFcn(app, @graphyAxis, true);
            app.graphYAxis.Value = '1';
            app.EndTelemetryButton = uibutton(app.UIFigure, 'push');
            app.EndTelemetryButton.ButtonPushedFcn = createCallbackFcn(app, @stopTimer, true);
            app.EndTelemetryButton.Text = 'End Telemetry';
            app.AltitudeLabel = uilabel(app.UIFigure);
            app.AltitudeLabel.Text = 'Altitude:';
            app.altText = uilabel(app.UIFigure);
            app.altText.Text = '0';
            app.currentStateButton = uibutton(app.UIFigure, 'push');
            app.currentStateButton.Text = 'State: 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gcs2020

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end